classdef tuner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NPMFOPDTunerUIFigure           matlab.ui.Figure
        TuneIOPIDcontrollerButton      matlab.ui.control.Button
        PlantgainkEditFieldLabel       matlab.ui.control.Label
        PlantgainkEditField            matlab.ui.control.NumericEditField
        NeighborhoodphasemargintunerforFOPIDcontrollerLabel  matlab.ui.control.Label
        NPMFOPIDTunerLabel             matlab.ui.control.Label
        PlanttimeconstanttauEditFieldLabel  matlab.ui.control.Label
        PlanttimeconstanttauEditField  matlab.ui.control.NumericEditField
        Systemcrossoverfrequencyw_cEditFieldLabel  matlab.ui.control.Label
        Systemcrossoverfrequencyw_cEditField  matlab.ui.control.NumericEditField
        SystemphasemarginphiLabel      matlab.ui.control.Label
        SystemphasemarginphiEditField  matlab.ui.control.NumericEditField
        k_pEditFieldLabel              matlab.ui.control.Label
        k_pEditField                   matlab.ui.control.NumericEditField
        k_iEditFieldLabel              matlab.ui.control.Label
        k_iEditField                   matlab.ui.control.NumericEditField
        k_dEditFieldLabel              matlab.ui.control.Label
        k_dEditField                   matlab.ui.control.NumericEditField
        TuneFOPDcontrollerButton       matlab.ui.control.Button
        k_pEditField_2Label            matlab.ui.control.Label
        k_pEditField_fo                matlab.ui.control.NumericEditField
        k_dEditField_3Label            matlab.ui.control.Label
        k_dEditField_fo                matlab.ui.control.NumericEditField
        lambdaEditFieldLabel           matlab.ui.control.Label
        lambdaEditField                matlab.ui.control.NumericEditField
        StatusLabel                    matlab.ui.control.Label
        NotrunningLabel                matlab.ui.control.Label
        k_iEditField_2Label            matlab.ui.control.Label
        k_iEditField_fo                matlab.ui.control.NumericEditField
        muEditFieldLabel               matlab.ui.control.Label
        muEditField                    matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Button pushed function: TuneIOPIDcontrollerButton
        function TuneIOPIDcontrollerButtonPushed(app, event)
            app.NotrunningLabel.Text='Running.';
            global K T1 wc phi %inputs
            K = app.PlantgainkEditField.Value; %plant gain
            T1 = app.PlanttimeconstanttauEditField.Value; %plant time constant
            wc = app.Systemcrossoverfrequencyw_cEditField.Value; %crossover frequency
            phi = app.SystemphasemarginphiEditField.Value; %phase margin
            pause on % to enable pause function
            pause(0.1);
            iopid_tune;
            %run_patternsearch_npm;
            %app.k_pEditField.Value=800;
            global iokp ioki iokd
            
            app.k_pEditField.Value=iokp;
            app.k_iEditField.Value=ioki;
            app.k_dEditField.Value=iokd;
            app.NotrunningLabel.Text='Ready.';
        end

        % Button pushed function: TuneFOPDcontrollerButton
        function TuneFOPDcontrollerButtonPushed(app, event)
            app.NotrunningLabel.Text='Running.';
            pause on
            pause(0.1);
            global omega_c phi_mr
            omega_c=app.Systemcrossoverfrequencyw_cEditField.Value;
            phi_mr=app.SystemphasemarginphiEditField.Value;
            run_patternsearch_npm;
            global x
            app.k_pEditField_fo.Value=x(1);
            app.k_iEditField_fo.Value=x(2);
            app.k_dEditField_fo.Value=x(3);
            app.lambdaEditField.Value=x(4);
            app.muEditField.Value=x(5);
            app.NotrunningLabel.Text='Ready.';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NPMFOPDTunerUIFigure = uifigure;
            app.NPMFOPDTunerUIFigure.Name = 'NPM FOPD Tuner';
            app.TuneIOPIDcontrollerButton = uibutton(app.NPMFOPDTunerUIFigure, 'push');
            app.TuneIOPIDcontrollerButton.ButtonPushedFcn = createCallbackFcn(app, @TuneIOPIDcontrollerButtonPushed, true);
            app.TuneIOPIDcontrollerButton.Text = 'Tune IOPID controller';
            app.PlantgainkEditFieldLabel = uilabel(app.NPMFOPDTunerUIFigure);
            app.PlantgainkEditFieldLabel.Text = 'Plant gain (k)';
            app.PlantgainkEditField = uieditfield(app.NPMFOPDTunerUIFigure, 'numeric');
            app.PlantgainkEditField.ValueDisplayFormat = '%11.5g';
            app.PlantgainkEditField.Value = 1.0263;
            app.NeighborhoodphasemargintunerforFOPIDcontrollerLabel = uilabel(app.NPMFOPDTunerUIFigure);
            app.NeighborhoodphasemargintunerforFOPIDcontrollerLabel.Text = 'Neighborhood phase margin tuner for FOPID controller';
            app.NPMFOPIDTunerLabel = uilabel(app.NPMFOPDTunerUIFigure);
            app.NPMFOPIDTunerLabel.Text = 'NPM FOPID Tuner';
            app.PlanttimeconstanttauEditFieldLabel = uilabel(app.NPMFOPDTunerUIFigure);
            app.PlanttimeconstanttauEditFieldLabel.Text = 'Plant time constant (tau)';
            app.PlanttimeconstanttauEditField = uieditfield(app.NPMFOPDTunerUIFigure, 'numeric');
            app.PlanttimeconstanttauEditField.Value = 0.71;
            app.Systemcrossoverfrequencyw_cEditFieldLabel = uilabel(app.NPMFOPDTunerUIFigure);
            app.Systemcrossoverfrequencyw_cEditFieldLabel.Text = 'System crossover frequency (w_c)';
            app.Systemcrossoverfrequencyw_cEditField = uieditfield(app.NPMFOPDTunerUIFigure, 'numeric');
            app.Systemcrossoverfrequencyw_cEditField.Value = 2.51;
            app.SystemphasemarginphiLabel = uilabel(app.NPMFOPDTunerUIFigure);
            app.SystemphasemarginphiLabel.Text = 'System phase margin (phi)';
            app.SystemphasemarginphiEditField = uieditfield(app.NPMFOPDTunerUIFigure, 'numeric');
            app.SystemphasemarginphiEditField.Value = 83.9;
            app.k_pEditFieldLabel = uilabel(app.NPMFOPDTunerUIFigure);
            app.k_pEditFieldLabel.Text = 'k_p';
            app.k_pEditField = uieditfield(app.NPMFOPDTunerUIFigure, 'numeric');
            app.k_iEditFieldLabel = uilabel(app.NPMFOPDTunerUIFigure);
            app.k_iEditFieldLabel.Text = 'k_i';
            app.k_iEditField = uieditfield(app.NPMFOPDTunerUIFigure, 'numeric');
            app.k_dEditFieldLabel = uilabel(app.NPMFOPDTunerUIFigure);
            app.k_dEditFieldLabel.Text = 'k_d';
            app.k_dEditField = uieditfield(app.NPMFOPDTunerUIFigure, 'numeric');
            app.TuneFOPDcontrollerButton = uibutton(app.NPMFOPDTunerUIFigure, 'push');
            app.TuneFOPDcontrollerButton.ButtonPushedFcn = createCallbackFcn(app, @TuneFOPDcontrollerButtonPushed, true);
            app.TuneFOPDcontrollerButton.Text = 'Tune FOPD controller';
            app.k_pEditField_2Label = uilabel(app.NPMFOPDTunerUIFigure);
            app.k_pEditField_2Label.Text = 'k_p';
            app.k_pEditField_fo = uieditfield(app.NPMFOPDTunerUIFigure, 'numeric');
            app.k_dEditField_3Label = uilabel(app.NPMFOPDTunerUIFigure);
            app.k_dEditField_3Label.Text = 'k_d';
            app.k_dEditField_fo = uieditfield(app.NPMFOPDTunerUIFigure, 'numeric');
            app.lambdaEditFieldLabel = uilabel(app.NPMFOPDTunerUIFigure);
            app.lambdaEditFieldLabel.Text = 'lambda';
            app.lambdaEditField = uieditfield(app.NPMFOPDTunerUIFigure, 'numeric');
            app.StatusLabel = uilabel(app.NPMFOPDTunerUIFigure);
            app.StatusLabel.Text = 'Status:';
            app.NotrunningLabel = uilabel(app.NPMFOPDTunerUIFigure);
            app.NotrunningLabel.Text = 'Not running.';
            app.k_iEditField_2Label = uilabel(app.NPMFOPDTunerUIFigure);
            app.k_iEditField_2Label.Text = 'k_i';
            app.k_iEditField_fo = uieditfield(app.NPMFOPDTunerUIFigure, 'numeric');
            app.muEditFieldLabel = uilabel(app.NPMFOPDTunerUIFigure);
            app.muEditFieldLabel.Text = 'mu';
            app.muEditField = uieditfield(app.NPMFOPDTunerUIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = tuner

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NPMFOPDTunerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NPMFOPDTunerUIFigure)
        end
    end
end