classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NPMFOPDTUNERUIFigure            matlab.ui.Figure
        NPMFOPDTunerLabel               matlab.ui.control.Label
        NeighborhoodphasemargintunerforFOPDcontrollerLabel  matlab.ui.control.Label
        StatusLabel                     matlab.ui.control.Label
        NotrunningLabel                 matlab.ui.control.Label
        RobustnessPanel                 matlab.ui.container.Panel
        Robustnessanalysisinlinearmodel5Button  matlab.ui.control.Button
        KfactorLabel                    matlab.ui.control.Label
        KfactorEditField                matlab.ui.control.NumericEditField
        RobustnessanalysisinSimscape78Button  matlab.ui.control.Button
        FOPDwBodeStepPanel              matlab.ui.container.Panel
        k_pEditField_2Label             matlab.ui.control.Label
        k_pEditField_fo                 matlab.ui.control.NumericEditField
        k_dEditField_3Label             matlab.ui.control.Label
        k_dEditField_fo                 matlab.ui.control.NumericEditField
        lambdaEditFieldLabel            matlab.ui.control.Label
        lambdaEditField                 matlab.ui.control.NumericEditField
        TuneFOPDcontroller23Button      matlab.ui.control.Button
        Stepresponsesinlinearmodel4Button  matlab.ui.control.Button
        BodeButton                      matlab.ui.control.Button
        StepresponsesinSimscape6Button  matlab.ui.control.Button
        StepresponsesinOS49Button       matlab.ui.control.Button
        IOPIDPanel                      matlab.ui.container.Panel
        TuneIOPIDcontroller1Button      matlab.ui.control.Button
        TuneIOPDcontrollerButton        matlab.ui.control.Button
        k_pEditFieldLabel               matlab.ui.control.Label
        k_pEditField                    matlab.ui.control.NumericEditField
        k_iEditFieldLabel               matlab.ui.control.Label
        k_iEditField                    matlab.ui.control.NumericEditField
        k_dEditFieldLabel               matlab.ui.control.Label
        k_dEditField                    matlab.ui.control.NumericEditField
        PlantparametersPanel            matlab.ui.container.Panel
        LoadSimscapeplantButton         matlab.ui.control.Button
        PlantgainkEditFieldLabel        matlab.ui.control.Label
        PlantgainkEditField             matlab.ui.control.NumericEditField
        PlanttimeconstanttauEditFieldLabel  matlab.ui.control.Label
        PlanttimeconstanttauEditField   matlab.ui.control.NumericEditField
        Systemcrossoverfrequencyw_cEditFieldLabel  matlab.ui.control.Label
        Systemcrossoverfrequencyw_cEditField  matlab.ui.control.NumericEditField
        SystemphasemarginphiEditFieldLabel  matlab.ui.control.Label
        SystemphasemarginphiEditField   matlab.ui.control.NumericEditField
        LoadOS4plantButton              matlab.ui.control.Button
        DiscretizationPanel             matlab.ui.container.Panel
        IRID1011Button                  matlab.ui.control.Button
        c2dButton                       matlab.ui.control.Button
        InfomationareaLabel             matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function [] = param2base(app)
            disp('Outputting parameters to base workspace.');
            assignin('base','k',app.PlantgainkEditField.Value);
            assignin('base','tau',app.PlanttimeconstanttauEditField.Value);
            %assignin('base','Lambda',lamda);
            assignin('base','Kp',app.k_pEditField_fo.Value);
            assignin('base','Kd',app.k_dEditField_fo.Value);
            assignin('base','Lambda',app.lambdaEditField.Value);
            assignin('base','iokp',app.k_pEditField.Value);
            assignin('base','ioki',app.k_iEditField.Value);
            assignin('base','iokd',app.k_dEditField.Value);

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TuneIOPIDcontroller1Button
        function TuneIOPIDcontroller1ButtonPushed(app, event)
            app.NotrunningLabel.Text='Running.';
            global K T1 wc phi %inputs
            K = app.PlantgainkEditField.Value; %plant gain
            T1 = app.PlanttimeconstanttauEditField.Value; %plant time constant
            wc = app.Systemcrossoverfrequencyw_cEditField.Value; %crossover frequency
            phi = app.SystemphasemarginphiEditField.Value; %phase margin
            pause on % to enable pause function
            pause(0.1);
            iopid_tune;
            %run_patternsearch_npm;
            %app.k_pEditField.Value=800;
            global iokp ioki iokd
            
            app.k_pEditField.Value=iokp;
            app.k_iEditField.Value=ioki;
            app.k_dEditField.Value=iokd;
            app.NotrunningLabel.Text='Ready.';   
        end

        % Button pushed function: TuneFOPDcontroller23Button
        function TuneFOPDcontroller23ButtonPushed(app, event)
            app.NotrunningLabel.Text='Running.';
            
            pause on
            pause(0.1);
            global K T1
            K = app.PlantgainkEditField.Value; %plant gain
            T1 = app.PlanttimeconstanttauEditField.Value; %plant time constant
            global omega_c phi_m
            omega_c=app.Systemcrossoverfrequencyw_cEditField.Value;
            phi_m=app.SystemphasemarginphiEditField.Value;
            global iokp ioki iokd
            iokp=app.k_pEditField.Value;
            ioki=app.k_iEditField.Value;
            iokd=app.k_dEditField.Value;
            %figure(2);
            run_patternsearch_npm;
            global lamda kd kp
            app.k_pEditField_fo.Value=kp;
%             app.k_iEditField_fo.Value=x(2);
            app.k_dEditField_fo.Value=kd;
            app.lambdaEditField.Value=lamda;
%             app.muEditField.Value=x(5);
            app.NotrunningLabel.Text='Ready.';
        end

        % Button pushed function: Stepresponsesinlinearmodel4Button
        function Stepresponsesinlinearmodel4ButtonPushed(app, event)
            app.NotrunningLabel.Text='Running.';
            
            pause on
            pause(0.1);
            
            param2base(app);
            

            figure(4)
            run_simulink_kd;
            %legend('FOPD','IOPID');
            xlabel('Time (second)');
            ylabel('Altitude (meter)');
            hline = findobj(gcf, 'type', 'line');
            set(hline,'LineWidth',1);            
            grid on
            app.NotrunningLabel.Text='Ready.';
        end

        % Button pushed function: LoadSimscapeplantButton
        function LoadSimscapeplantButtonPushed(app, event)
            app.PlantgainkEditField.Value = 1.102; %plant gain (k)
            app.PlanttimeconstanttauEditField.Value = 0.179; %(T1) plant time constant

        end

        % Button pushed function: TuneIOPDcontrollerButton
        function TuneIOPDcontrollerButtonPushed(app, event)
            app.NotrunningLabel.Text='Running.';
            global K T1 wc phi %inputs
            K = app.PlantgainkEditField.Value; %plant gain
            T1 = app.PlanttimeconstanttauEditField.Value; %plant time constant
            wc = app.Systemcrossoverfrequencyw_cEditField.Value; %crossover frequency
            phi = app.SystemphasemarginphiEditField.Value; %phase margin
            pause on % to enable pause function
            pause(0.1);
            iopd_tune;
            %run_patternsearch_npm;
            %app.k_pEditField.Value=800;
            global iokp ioki iokd
            
            app.k_pEditField.Value=iokp;
            app.k_iEditField.Value=ioki;
            app.k_dEditField.Value=iokd;
            app.NotrunningLabel.Text='Ready.';    
        end

        % Button pushed function: 
        % Robustnessanalysisinlinearmodel5Button
        function Robustnessanalysisinlinearmodel5ButtonPushed(app, event)
%             figure(3);
%             hold on
            app.NotrunningLabel.Text='Running.';
            factor=app.KfactorEditField.Value;
            pause on
            pause(0.1);
            figure(5);hold off
%             figure(4);hold off
            run_simulink_kd;
            itae_f0=ITAE_F.Data(end);
            itae_i0=ITAE_I.Data(end);
            hold on
            global K 
            K0=K;
            K=K0/factor;
            run_simulink_kd;
            itae_f1=ITAE_F.Data(end);
            itae_i1=ITAE_I.Data(end);
            K=K0*factor;
            run_simulink_kd;
            itae_f2=ITAE_F.Data(end);
            itae_i2=ITAE_I.Data(end);
            K=K0;
            hold off
            %figure(5);
            legend(['FOPD: K=' num2str(K) ', ITAE=' num2str(itae_f0)],...
                ['IOPID: K=' num2str(K) ', ITAE=' num2str(itae_i0)],...
                ['FOPD: K=' num2str(K) '/' num2str(factor) ', ITAE=' num2str(itae_f1)],...
                ['IOPID: K=' num2str(K) '/' num2str(factor) ', ITAE=' num2str(itae_i1)],...
                ['FOPD: K=' num2str(K) '*' num2str(factor) ', ITAE=' num2str(itae_f2)],...
                ['IOPID: K=' num2str(K) '*' num2str(factor) ', ITAE=' num2str(itae_i2)]...
                )
%             legend(['FOPD: K=' num2str(K)],...
%                 ['IOPID: K=' num2str(K) ', I=' num2str(ioki)],...
%                 ['FOPD: K=' num2str(K) '/' num2str(factor)],...
%                 ['IOPID: K=' num2str(K) '/' num2str(factor) ', I=' num2str(ioki)],...
%                 ['FOPD: K=' num2str(K) '*' num2str(factor)],...
%                 ['IOPID: K=' num2str(K) '*' num2str(factor) ', I=' num2str(ioki)]...
%                 )
%             figure(4);
%             legend(['FOPD: K=' num2str(K)],...
%                 ['IOPID: K=' num2str(K) ', I=' num2str(ioki)],...
%                 ['FOPD: K=' num2str(K) '*0.5'],...
%                 ['IOPID: K=' num2str(K) '*0.5' ', I=' num2str(ioki)],...
%                 ['FOPD: K=' num2str(K) '*1.5'],...
%                 ['IOPID: K=' num2str(K) '*1.5' ', I=' num2str(ioki)]...
%                 )
            xlabel('Time (second)');
            ylabel('Altitude (meter)');
            hline = findobj(gcf, 'type', 'line');
            set(hline,'LineWidth',1);
            app.NotrunningLabel.Text='Ready.';
        end

        % Button pushed function: IRID1011Button
        function IRID1011ButtonPushed(app, event)
            gam=app.lambdaEditField.Value;
            Ts=0.01;
            dfod=irid_fod(gam,Ts,5);
            assignin('base','dfod',dfod);
        end

        % Button pushed function: c2dButton
        function c2dButtonPushed(app, event)
%            CreateStruct.Interpreter = 'tex';
%            CreateStruct.WindowStyle = 'modal';
%            h=msgbox('Z = X^2 + Y^2','Value',CreateStruct); 
            % use text area to show info.
        end

        % Button pushed function: BodeButton
        function BodeButtonPushed(app, event)
            app.NotrunningLabel.Text='Busy.';
            global K T1 wc phi %inputs
            K = app.PlantgainkEditField.Value; %plant gain
            T1 = app.PlanttimeconstanttauEditField.Value; %plant time constant
            wc = app.Systemcrossoverfrequencyw_cEditField.Value; %crossover frequency
            phi = app.SystemphasemarginphiEditField.Value; %phase margin
            k=K;tau=T1;

            global iokp ioki iokd
            iokp=app.k_pEditField.Value;
            ioki=app.k_iEditField.Value;
            iokd=app.k_dEditField.Value;         
            
            global kp kd lamda
            kp=app.k_pEditField_fo.Value;
            kd=app.k_dEditField_fo.Value;
            lamda=app.lambdaEditField.Value;
            lambda=lamda;

            s=fotf('s');
            c_tf=kp*(1+kd*(s^lambda));%Test
            p_tf=k/(tau*s+1)/s;
            sys_tf=c_tf*p_tf;
            %
            ioc_tf=iokp+ioki/s+iokd*s;
            p_tf=k/(tau*s+1)/s;
            iosys_tf=ioc_tf*p_tf;
            %
            figure(3);
            bode(sys_tf);
            grid on
            hold on
            bode(iosys_tf);
            legend('FOPD','IOPID');
            hold off
            app.NotrunningLabel.Text='Ready.';
        end

        % Button pushed function: StepresponsesinSimscape6Button
        function StepresponsesinSimscape6ButtonPushed(app, event)
            app.NotrunningLabel.Text='Running.';
            
            pause on
            pause(0.1);
            %run_simulink_kd;
            uiopen('/Users/standard/Library/Mobile Documents/com~apple~CloudDocs/Access/FOFC_Benchmark/Simscape/QuadRotor/Models/Quadrotor_Controller.slx',1)
            %assignin('base','gain',dfod);
            assignin('base','i_enable',0);
            assignin('base','f_enable',1);
            assignin('base','gain',1);
            param2base(app);
            [t_time,x_state,y_out]=sim('Quadrotor_Controller.slx',[0,20]);
             global itae_f_Simscape
             itae_f_Simscape=itae.Data(end);
            figure(6)

            plot(Altitude_Meas.Time,Altitude_Meas.Data,'-',...
                'DisplayName',['FOPD: ITAE=' num2str(itae_f_Simscape) ', ITSE=' num2str(itse.Data(end))]);
%             legend(h1,['FOPD: ITAE=' num2str(itae_f_Simscape)])
            hold on
            assignin('base','i_enable',1);
            assignin('base','f_enable',0);
            assignin('base','gain',1);
            [t_time,x_state,y_out]=sim('Quadrotor_Controller.slx',[0,20]);
             global itae_i_Simscape
             itae_i_Simscape=itae.Data(end);
            figure(6)
            plot(Altitude_Meas.Time,Altitude_Meas.Data,'--',...
                'DisplayName',['IOPID: ITAE=' num2str(itae_i_Simscape) ', ITSE=' num2str(itse.Data(end))]);
            %legend('FOPD','IOPID')
            %legend(h,['FOPD: K=' num2str(K)],['IOPID: K=' num2str(K) ', I=' num2str(ioki)])
            %legend(h,['FOPD: ITAE=' num2str(itae_f_Simscape)],...
                %['IOPID: ITAE=' num2str(itae_i_Simscape)]);
            %legend(h2,['IOPID: ITAE=' num2str(itae_i_Simscape)]);
            
            
            %legend('FOPD','IOPID');
            legend
            xlabel('Time (second)');
            ylabel('Altitude (meter)');
            hline = findobj(gcf, 'type', 'line');
            set(hline,'LineWidth',1);
            grid on
            hold off
            app.NotrunningLabel.Text='Ready.';
        end

        % Button pushed function: 
        % RobustnessanalysisinSimscape78Button
        function RobustnessanalysisinSimscape78ButtonPushed(app, event)
            app.NotrunningLabel.Text='Running.';
            factor=app.KfactorEditField.Value;
            pause on
            pause(0.1);
            %run_simulink_kd;
            
            uiopen('/Users/standard/Library/Mobile Documents/com~apple~CloudDocs/Access/FOFC_Benchmark/Simscape/QuadRotor/Models/Quadrotor_Controller.slx',1)
%%%%%%%%%%%FOPD gain=1
            assignin('base','i_enable',0);
            assignin('base','f_enable',1);
            assignin('base','gain',1);
            [t_time,x_state,y_out]=sim('Quadrotor_Controller.slx',[0,20]);
             global itae_f_Simscape
             itae_f_Simscape=itae.Data(end);
            figure(7)

            plot(Altitude_Meas.Time,Altitude_Meas.Data,'-',...
                'DisplayName',['FOPD (k): ITAE=' num2str(itae_f_Simscape) ', ITSE=' num2str(itse.Data(end))]);
%             legend(h1,['FOPD: ITAE=' num2str(itae_f_Simscape)])
            hold on
%%%%%%%%%%%%IOPID gain=1 
            assignin('base','i_enable',1);
            assignin('base','f_enable',0);
            assignin('base','gain',1);
            [t_time,x_state,y_out]=sim('Quadrotor_Controller.slx',[0,20]);
             global itae_i_Simscape
             itae_i_Simscape=itae.Data(end);
            figure(8)
            plot(Altitude_Meas.Time,Altitude_Meas.Data,'--',...
                'DisplayName',['IOPID (k): ITAE=' num2str(itae_i_Simscape) ', ITSE=' num2str(itse.Data(end))]);
            hold on
%%%%%%%%%%%%FOPD gain=factor            
            assignin('base','i_enable',0);
            assignin('base','f_enable',1);
            assignin('base','gain',factor);
            [t_time,x_state,y_out]=sim('Quadrotor_Controller.slx',[0,20]);
            figure(7);

            plot(Altitude_Meas.Time,Altitude_Meas.Data,'-',...
                'DisplayName',['FOPD (k*' num2str(factor) '): ITAE=' num2str(itae.Data(end)) ', ITSE=' num2str(itse.Data(end))]);
                
%%%%%%%%%%%%IOPID gain=factor            
            assignin('base','i_enable',1);
            assignin('base','f_enable',0);
            assignin('base','gain',factor);
            [t_time,x_state,y_out]=sim('Quadrotor_Controller.slx',[0,20]);
            figure(8);

            plot(Altitude_Meas.Time,Altitude_Meas.Data,'--',...
                'DisplayName',['IOPID (k*' num2str(factor) '): ITAE=' num2str(itae.Data(end)) ', ITSE=' num2str(itse.Data(end))]);
                
              
%%%%%%%%%%%%FOPD gain=1/factor            
            assignin('base','i_enable',0);
            assignin('base','f_enable',1);
            assignin('base','gain',1/factor);
            [t_time,x_state,y_out]=sim('Quadrotor_Controller.slx',[0,20]);
            figure(7);

            plot(Altitude_Meas.Time,Altitude_Meas.Data,'-',...
                'DisplayName',['FOPD (k/' num2str(factor) '): ITAE=' num2str(itae.Data(end)) ', ITSE=' num2str(itse.Data(end))]);
            legend
            xlabel('Time (second)');
            ylabel('Altitude (meter)')
            hline = findobj(gcf, 'type', 'line');
            set(hline,'LineWidth',1);
            grid on
            hold off

%%%%%%%%%%%%IOPID gain=1/factor            
            assignin('base','i_enable',1);
            assignin('base','f_enable',0);
            assignin('base','gain',1/factor);
            [t_time,x_state,y_out]=sim('Quadrotor_Controller.slx',[0,20]);
            figure(8);

            plot(Altitude_Meas.Time,Altitude_Meas.Data,'--',...
                'DisplayName',['IOPID (k/' num2str(factor) '): ITAE=' num2str(itae.Data(end)) ', ITSE=' num2str(itse.Data(end))]);


            %legend('FOPD','IOPID')
            %legend(h,['FOPD: K=' num2str(K)],['IOPID: K=' num2str(K) ', I=' num2str(ioki)])
            %legend(h,['FOPD: ITAE=' num2str(itae_f_Simscape)],...
                %['IOPID: ITAE=' num2str(itae_i_Simscape)]);
            %legend(h2,['IOPID: ITAE=' num2str(itae_i_Simscape)]);
            
            
            %legend('FOPD','IOPID');
            legend
            xlabel('Time (second)');
            ylabel('Altitude (meter)');
            hline = findobj(gcf, 'type', 'line');
            set(hline,'LineWidth',1);

            grid on
            hold off
            app.NotrunningLabel.Text='Ready.';
        end

        % Button pushed function: LoadOS4plantButton
        function LoadOS4plantButtonPushed(app, event)
            app.PlantgainkEditField.Value = 0.86; %plant gain (k)
            app.PlanttimeconstanttauEditField.Value = 1.07; %(T1) plant time constant
            app.Systemcrossoverfrequencyw_cEditField.Value = 1.2;
            app.SystemphasemarginphiEditField.Value = 83.9;
        end

        % Button pushed function: StepresponsesinOS49Button
        function StepresponsesinOS49ButtonPushed(app, event)
            app.NotrunningLabel.Text='Running.';
            
            pause on
            pause(0.1);
            
            %run_simulink_kd;
            uiopen('/Users/standard/Library/Mobile Documents/com~apple~CloudDocs/Access/FOFC_Benchmark/OS4/systema_script.slx',1)
            %uiopen('/Users/standard/Library/Mobile Documents/com~apple~CloudDocs/Access/FOFC_Benchmark/Simscape/QuadRotor/Models/Quadrotor_Controller.slx',1)
            optsiminit;
            param2base(app);
            IRID1011ButtonPushed(app, event);
            %assignin('base','gain',dfod);
            assignin('base','i_enable',0);
            assignin('base','f_enable',1);
            assignin('base','gain',1);
            [t_time,x_state,y_out]=sim('systema_script.slx',[0,15]);
             global itae_f_Simscape
             itae_f_Simscape=itae.Data(end);
            figure(9)

            plot(Altitude_Meas.Time,Altitude_Meas.Data,'-',...
                'DisplayName',['FOPD: ITAE=' num2str(itae_f_Simscape) ', ITSE=' num2str(itse.Data(end))]);
%             legend(h1,['FOPD: ITAE=' num2str(itae_f_Simscape)])
            hold on
            optsiminit;
            assignin('base','i_enable',1);
            assignin('base','f_enable',0);
            assignin('base','gain',1);
            [t_time,x_state,y_out]=sim('systema_script.slx',[0,15]);
             global itae_i_Simscape
             itae_i_Simscape=itae.Data(end);
            plot(Altitude_Meas.Time,Altitude_Meas.Data,'--',...
                'DisplayName',['IOPID: ITAE=' num2str(itae_i_Simscape) ', ITSE=' num2str(itse.Data(end))]);
            %legend('FOPD','IOPID')
            %legend(h,['FOPD: K=' num2str(K)],['IOPID: K=' num2str(K) ', I=' num2str(ioki)])
            %legend(h,['FOPD: ITAE=' num2str(itae_f_Simscape)],...
                %['IOPID: ITAE=' num2str(itae_i_Simscape)]);
            %legend(h2,['IOPID: ITAE=' num2str(itae_i_Simscape)]);
            
            
            %legend('FOPD','IOPID');
            legend
            xlabel('Time (second)');
            ylabel('Altitude (meter)');
            hline = findobj(gcf, 'type', 'line');
            set(hline,'LineWidth',1);
            grid on
            hold off
            app.NotrunningLabel.Text='Ready.';
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NPMFOPDTUNERUIFigure = uifigure('Visible', 'off');
            app.NPMFOPDTUNERUIFigure.Name = 'NPM FOPD TUNER';
            app.NPMFOPDTunerLabel = uilabel(app.NPMFOPDTUNERUIFigure);
            app.NPMFOPDTunerLabel.Text = 'NPM FOPD Tuner';
            app.NeighborhoodphasemargintunerforFOPDcontrollerLabel = uilabel(app.NPMFOPDTUNERUIFigure);
            app.NeighborhoodphasemargintunerforFOPDcontrollerLabel.Text = 'Neighborhood phase margin tuner for FOPD controller';
            app.StatusLabel = uilabel(app.NPMFOPDTUNERUIFigure);
            app.StatusLabel.Text = 'Status:';
            app.NotrunningLabel = uilabel(app.NPMFOPDTUNERUIFigure);
            app.NotrunningLabel.Text = 'Not running.';
            app.RobustnessPanel = uipanel(app.NPMFOPDTUNERUIFigure);
            app.RobustnessPanel.Title = '4. Robustness';
            app.Robustnessanalysisinlinearmodel5Button = uibutton(app.RobustnessPanel, 'push');
            app.Robustnessanalysisinlinearmodel5Button.ButtonPushedFcn = createCallbackFcn(app, @Robustnessanalysisinlinearmodel5ButtonPushed, true);
            app.Robustnessanalysisinlinearmodel5Button.Text = {'Robustness analysis '; 'in linear model (5)'};
            app.KfactorLabel = uilabel(app.RobustnessPanel);
            app.KfactorLabel.Text = 'K factor:';
            app.KfactorEditField = uieditfield(app.RobustnessPanel, 'numeric');
            app.KfactorEditField.Value = 2;
            app.RobustnessanalysisinSimscape78Button = uibutton(app.RobustnessPanel, 'push');
            app.RobustnessanalysisinSimscape78Button.ButtonPushedFcn = createCallbackFcn(app, @RobustnessanalysisinSimscape78ButtonPushed, true);
            app.RobustnessanalysisinSimscape78Button.Text = {'Robustness analysis '; 'in Simscape (7, 8)'};
            app.FOPDwBodeStepPanel = uipanel(app.NPMFOPDTUNERUIFigure);
            app.FOPDwBodeStepPanel.Title = '3. FOPD w/ Bode & Step';
            app.k_pEditField_2Label = uilabel(app.FOPDwBodeStepPanel);
            app.k_pEditField_2Label.Text = 'k_p';
            app.k_pEditField_fo = uieditfield(app.FOPDwBodeStepPanel, 'numeric');
            app.k_dEditField_3Label = uilabel(app.FOPDwBodeStepPanel);
            app.k_dEditField_3Label.Text = 'k_d';
            app.k_dEditField_fo = uieditfield(app.FOPDwBodeStepPanel, 'numeric');
            app.lambdaEditFieldLabel = uilabel(app.FOPDwBodeStepPanel);
            app.lambdaEditFieldLabel.Text = 'lambda';
            app.lambdaEditField = uieditfield(app.FOPDwBodeStepPanel, 'numeric');
            app.TuneFOPDcontroller23Button = uibutton(app.FOPDwBodeStepPanel, 'push');
            app.TuneFOPDcontroller23Button.ButtonPushedFcn = createCallbackFcn(app, @TuneFOPDcontroller23ButtonPushed, true);
            app.TuneFOPDcontroller23Button.Text = 'Tune FOPD controller (2, 3)';
            app.Stepresponsesinlinearmodel4Button = uibutton(app.FOPDwBodeStepPanel, 'push');
            app.Stepresponsesinlinearmodel4Button.ButtonPushedFcn = createCallbackFcn(app, @Stepresponsesinlinearmodel4ButtonPushed, true);
            app.Stepresponsesinlinearmodel4Button.Text = {'Step responses'; ' in linear model (4)'};
            app.BodeButton = uibutton(app.FOPDwBodeStepPanel, 'push');
            app.BodeButton.ButtonPushedFcn = createCallbackFcn(app, @BodeButtonPushed, true);
            app.BodeButton.Text = 'Bode';
            app.StepresponsesinSimscape6Button = uibutton(app.FOPDwBodeStepPanel, 'push');
            app.StepresponsesinSimscape6Button.ButtonPushedFcn = createCallbackFcn(app, @StepresponsesinSimscape6ButtonPushed, true);
            app.StepresponsesinSimscape6Button.Text = {'Step responses'; ' in Simscape (6)'};
            app.StepresponsesinOS49Button = uibutton(app.FOPDwBodeStepPanel, 'push');
            app.StepresponsesinOS49Button.ButtonPushedFcn = createCallbackFcn(app, @StepresponsesinOS49ButtonPushed, true);
            app.StepresponsesinOS49Button.Text = {'Step responses'; ' in OS4 (9)'};
            app.IOPIDPanel = uipanel(app.NPMFOPDTUNERUIFigure);
            app.IOPIDPanel.Title = '2. IOPID';
            app.TuneIOPIDcontroller1Button = uibutton(app.IOPIDPanel, 'push');
            app.TuneIOPIDcontroller1Button.ButtonPushedFcn = createCallbackFcn(app, @TuneIOPIDcontroller1ButtonPushed, true);
            app.TuneIOPIDcontroller1Button.Text = 'Tune IOPID controller (1)';
            app.TuneIOPDcontrollerButton = uibutton(app.IOPIDPanel, 'push');
            app.TuneIOPDcontrollerButton.ButtonPushedFcn = createCallbackFcn(app, @TuneIOPDcontrollerButtonPushed, true);
            app.TuneIOPDcontrollerButton.Text = 'Tune IOPD controller';
            app.k_pEditFieldLabel = uilabel(app.IOPIDPanel);
            app.k_pEditFieldLabel.Text = 'k_p';
            app.k_pEditField = uieditfield(app.IOPIDPanel, 'numeric');
            app.k_iEditFieldLabel = uilabel(app.IOPIDPanel);
            app.k_iEditFieldLabel.Text = 'k_i';
            app.k_iEditField = uieditfield(app.IOPIDPanel, 'numeric');
            app.k_dEditFieldLabel = uilabel(app.IOPIDPanel);
            app.k_dEditFieldLabel.Text = 'k_d';
            app.k_dEditField = uieditfield(app.IOPIDPanel, 'numeric');
            app.PlantparametersPanel = uipanel(app.NPMFOPDTUNERUIFigure);
            app.PlantparametersPanel.Title = '1. Plant parameters';
            app.LoadSimscapeplantButton = uibutton(app.PlantparametersPanel, 'push');
            app.LoadSimscapeplantButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSimscapeplantButtonPushed, true);
            app.LoadSimscapeplantButton.Text = 'Load Simscape plant';
            app.PlantgainkEditFieldLabel = uilabel(app.PlantparametersPanel);
            app.PlantgainkEditFieldLabel.Text = 'Plant gain (k)';
            app.PlantgainkEditField = uieditfield(app.PlantparametersPanel, 'numeric');
            app.PlantgainkEditField.ValueDisplayFormat = '%11.5g';
            app.PlantgainkEditField.Value = 1.0263;
            app.PlanttimeconstanttauEditFieldLabel = uilabel(app.PlantparametersPanel);
            app.PlanttimeconstanttauEditFieldLabel.Text = 'Plant time constant (tau)';
            app.PlanttimeconstanttauEditField = uieditfield(app.PlantparametersPanel, 'numeric');
            app.PlanttimeconstanttauEditField.Value = 0.71;
            app.Systemcrossoverfrequencyw_cEditFieldLabel = uilabel(app.PlantparametersPanel);
            app.Systemcrossoverfrequencyw_cEditFieldLabel.Text = 'System crossover frequency (w_c)';
            app.Systemcrossoverfrequencyw_cEditField = uieditfield(app.PlantparametersPanel, 'numeric');
            app.Systemcrossoverfrequencyw_cEditField.Value = 2.51;
            app.SystemphasemarginphiEditFieldLabel = uilabel(app.PlantparametersPanel);
            app.SystemphasemarginphiEditFieldLabel.Text = 'System phase margin (phi)';
            app.SystemphasemarginphiEditField = uieditfield(app.PlantparametersPanel, 'numeric');
            app.SystemphasemarginphiEditField.Value = 83.9;
            app.LoadOS4plantButton = uibutton(app.PlantparametersPanel, 'push');
            app.LoadOS4plantButton.ButtonPushedFcn = createCallbackFcn(app, @LoadOS4plantButtonPushed, true);
            app.LoadOS4plantButton.Text = 'Load OS4 plant';
            app.DiscretizationPanel = uipanel(app.NPMFOPDTUNERUIFigure);
            app.DiscretizationPanel.Title = '5. Discretization';
            app.IRID1011Button = uibutton(app.DiscretizationPanel, 'push');
            app.IRID1011Button.ButtonPushedFcn = createCallbackFcn(app, @IRID1011ButtonPushed, true);
            app.IRID1011Button.Text = 'IRID (10,11)';
            app.c2dButton = uibutton(app.DiscretizationPanel, 'push');
            app.c2dButton.ButtonPushedFcn = createCallbackFcn(app, @c2dButtonPushed, true);
            app.c2dButton.Text = 'c2d';
            app.InfomationareaLabel = uilabel(app.NPMFOPDTUNERUIFigure);
            app.InfomationareaLabel.Text = 'Infomation area.';
            % Show the figure after all components are created
            app.NPMFOPDTUNERUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NPMFOPDTUNERUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NPMFOPDTUNERUIFigure)
        end
    end
end